classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChafFittingUIFigure            matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        InputDropDownLabel             matlab.ui.control.Label
        InputDropDown                  matlab.ui.control.DropDown
        ChafFittingLabel               matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        PointsNumEditFieldLabel        matlab.ui.control.Label
        PointsNumEditField             matlab.ui.control.NumericEditField
        PolynomialInterpolationCheckBox  matlab.ui.control.CheckBox
        GaussianInterpolationCheckBox  matlab.ui.control.CheckBox
        PolynomialApproximationCheckBox  matlab.ui.control.CheckBox
        FourierApproximationCheckBox   matlab.ui.control.CheckBox
        AxisActiveButton               matlab.ui.control.StateButton
        PolynomialTermsSpinnerLabel    matlab.ui.control.Label
        PolynomialTermsSpinner         matlab.ui.control.Spinner
        FourierTermsSpinnerLabel       matlab.ui.control.Label
        FourierTermsSpinner            matlab.ui.control.Spinner
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        AddPointsButton                matlab.ui.control.StateButton
        EditPointsButton               matlab.ui.control.StateButton
        DeletePointsButton             matlab.ui.control.StateButton
        errorLabel                     matlab.ui.control.Label
        error1                         matlab.ui.control.Label
        error2                         matlab.ui.control.Label
        error3                         matlab.ui.control.Label
        error4                         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        PointsX=[];
        PointsY=[];
        X=0;
        Y=0;
        SelectIndex=nan;
        FourierApproxX=[];
        FourierApproxY=[];
        PolyApproxX=[];
        PolyApproxY=[];
        PolyInterX=[];
        PolyInterY=[];
        GaussInterX=[];
        GaussInterY=[];
        count=0;
        drawFlag=false;
    end
    
    methods (Access = public)
        
        function [] = PlotAll(app)
            cla(app.UIAxes);
            if ~isempty(app.PointsX)
                lx=app.UIAxes.XLim(1);
                rx=app.UIAxes.XLim(2);
                hold(app.UIAxes,'on');
                label=[];         
                if ~isempty(app.PointsX)
                    scatter(app.UIAxes,app.PointsX,app.PointsY,"black");
                    label=[label "Points"];
                end
                if ~isnan(app.SelectIndex)
                    scatter(app.UIAxes,app.PointsX(app.SelectIndex),app.PointsY(app.SelectIndex),"red");
                    label=[label "Select Point"];
                end
                
                if app.PolynomialInterpolationCheckBox.Value
                    % Polynomial Interpolation
                    [lambda]=PolynomialInterpolation(app.PointsX,app.PointsY);
                    app.PolyInterX=lx:0.01:rx;
                    app.PolyInterY=Polynomial(lambda,app.PolyInterX,length(lambda)-1);
                    app.error1.Text=num2str(sqrt(mean((Polynomial(lambda,app.PointsX,length(lambda)-1)-app.PointsY).^2)));
                    plot(app.UIAxes,app.PolyInterX,app.PolyInterY,"blue");
                    label=[label "Polynomial Interpolation"];
                end
                if app.GaussianInterpolationCheckBox.Value
                    % Gaussian Interpolation
                    [lambda,u]=GaussInterpolation(app.PointsX,app.PointsY);
                    app.GaussInterX=lx:0.01:rx;
                    app.GaussInterY=Gauss(lambda,u,app.GaussInterX);
                    app.error2.Text=num2str(sqrt(mean((Gauss(lambda,u,app.PointsX)-app.PointsY).^2)));
                    plot(app.UIAxes,app.GaussInterX,app.GaussInterY,"green");
                    label=[label "Gaussian Interpolation"];
                    
                end
                if app.PolynomialApproximationCheckBox.Value
                    % Polynomial Approximation
                    [lambda]=PolynomialApproximation(app.PointsX,app.PointsY,app.PolynomialTermsSpinner.Value);
                    app.PolyApproxX=lx:0.01:rx;
                    app.PolyApproxY=Polynomial(lambda,app.PolyApproxX,app.PolynomialTermsSpinner.Value);
                    app.error3.Text=num2str(sqrt(mean((Polynomial(lambda,app.PointsX,app.PolynomialTermsSpinner.Value)-app.PointsY).^2)));
                    plot(app.UIAxes,app.PolyApproxX,app.PolyApproxY,"m");
                    label=[label "Polynomial Approximation"];
                end
                if app.FourierApproximationCheckBox.Value
                    % Fourier Approximation
                    lambda=FourierApproximation(app.PointsX,app.PointsY,app.FourierTermsSpinner.Value);
                    app.FourierApproxX=lx:0.01:rx;
                    app.FourierApproxY=Fourier(lambda,app.FourierApproxX,app.FourierTermsSpinner.Value);
                    app.error4.Text=num2str(sqrt(mean((Fourier(lambda,app.PointsX,app.FourierTermsSpinner.Value)-app.PointsY).^2)));
                    plot(app.UIAxes,app.FourierApproxX,app.FourierApproxY,"c");
                    label=[label "Fourier Approximation"];
                end
                legend(app.UIAxes,label);
                hold(app.UIAxes,'off');
            end      
            
        end
        
        function [] = ClearAll(app)
            app.error1.Text="0";
            app.error2.Text="0";
            app.error3.Text="0";
            app.error4.Text="0";
            app.PointsX=[];
            app.PointsY=[];
            app.SelectIndex=nan;
            app.FourierApproxX=[];
            app.FourierApproxY=[];
            app.PolyApproxX=[];
            app.PolyApproxY=[];
            app.PolyInterX=[];
            app.PolyInterY=[];
            app.GaussInterX=[];
            app.GaussInterY=[];
            cla(app.UIAxes);
            app.PointsNumEditField.Value=0;
            app.UIAxes.YLim=[0 1];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ClearAll();
        end

        % Window button down function: ChafFittingUIFigure
        function ChafFittingUIFigureWindowButtonDown(app, event)
            if app.InputDropDown.Value=="Manual"&&app.ChafFittingUIFigure.SelectionType=="normal"
                if app.AxisActiveButton.Value==true
                    dist=((app.PointsX-app.X)/(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))).^2+...
                    ((app.PointsY-app.Y)/(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))).^2;
                    [minValue, index]=min(dist);
                    if minValue<0.001
                        app.SelectIndex=index;
                    else
                        app.PointsX=[app.PointsX app.X];
                        app.PointsY=[app.PointsY app.Y];
                        app.PointsNumEditField.Value=length(app.PointsX);
                        app.SelectIndex=length(app.PointsX);
                    end
                end
                app.PlotAll();
            end
        end

        % Window button motion function: ChafFittingUIFigure
        function UIFigureWindowButtonMotion(app, event)
            currPt = app.UIAxes.CurrentPoint;
                xp = currPt(1,1);
                yp = currPt(1,2);
                
                if xp < app.UIAxes.XLim(1,1)||...
                        xp > app.UIAxes.XLim(1,2)||...
                        yp < app.UIAxes.YLim(1,1)||...
                        yp > app.UIAxes.YLim(1,2)
                   
                    app.X = 0;
                    app.Y = 0;
                   
                else
                    app.X = xp;
                    app.Y = yp;
                end
                if app.ChafFittingUIFigure.SelectionType=="normal"&&app.AxisActiveButton.Value==true...
                        &&~isnan(app.SelectIndex)
                    app.PointsX(app.SelectIndex)=app.X;
                    app.PointsY(app.SelectIndex)=app.Y;
                    app.PlotAll();
                end
        end

        % Value changed function: PointsNumEditField
        function PointsNumEditFieldValueChanged(app, event)
            value = app.PointsNumEditField.Value;
            app.ClearAll();
            app.PointsNumEditField.Value=value;
            app.PointsX=rand(1,value)*(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))+app.UIAxes.XLim(1);
            app.PointsY=rand(1,value)*(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))+app.UIAxes.YLim(1);
            app.PlotAll();
        end

        % Value changed function: InputDropDown
        function InputDropDownValueChanged(app, event)
            value = app.InputDropDown.Value;
            if value=="Manual"
                app.PointsNumEditField.Editable=false;
                app.AxisActiveButton.Visible=true;
                app.AxisActiveButton.Value=true;
            else
                app.PointsNumEditField.Editable=true;
                app.AxisActiveButton.Visible=false;
                app.AxisActiveButton.Value=false;
            end
        end

        % Callback function
        function DrawButtonPushed(app, event)
%             lx=app.UIAxes.XLim(1);
%             rx=app.UIAxes.XLim(2);
%             if ~isempty(app.PointsX)
%                 if app.DropDown.Value=="Interpolation"
%                     if app.AlgorithmDropDown.Value=="Polynomial"
%                        [lambda]=PolynomialInterpolation(app.PointsX,app.PointsY);
%                        app.PolyInterX=lx:0.01:rx;
%                        app.PolyInterY=Polynomial(lambda,app.PolyInterX,length(lambda)-1);
%                     elseif app.AlgorithmDropDown.Value=="Gaussian"
%                        [lambda,u]=GaussInterpolation(app.PointsX,app.PointsY);
%                        app.GaussInterX=lx:0.01:rx;
%                        app.GaussInterY=Gauss(lambda,u,app.GaussInterX);
%                     end
%                 else
%                     if app.AlgorithmDropDown.Value=="Polynomial"
%                        [lambda]=PolynomialApproximation(app.PointsX,app.PointsY,app.PolynomialTermsEditField.Value);
%                        app.PolyApproxX=lx:0.01:rx;
%                        app.PolyApproxY=Polynomial(lambda,app.PolyApproxX,app.PolynomialTermsEditField.Value);
%                     elseif app.AlgorithmDropDown.Value=="Fourier"
%                        lambda=FourierApproximation(app.PointsX,app.PointsY,app.PolynomialTermsEditField.Value);
%                        app.FourierApproxX=lx:0.01:rx;
%                        app.FourierApproxY=Fourier(lambda,app.FourierApproxX,app.PolynomialTermsEditField.Value);
%                     end
%                 end
%             end
            app.PlotAll();
%             cla(app.UIAxes);
%             hold(app.UIAxes,'on');
%             label=[];
%             scatter(app.UIAxes,app.PointsX,app.PointsY,"black");
%             plot(app.UIAxes,app.PolyInterX,app.PolyInterY);
%             plot(app.UIAxes,app.GaussInterX,app.GaussInterY);
%             plot(app.UIAxes,app.PolyApproxX,app.PolyApproxY);
%             plot(app.UIAxes,app.FourierApproxX,app.FourierApproxY);
%             
%             if ~isempty(app.PointsX)
%                 label=[label "Points"];
%             end
%             if ~isempty(app.PolyInterX)
%                 label=[label "Polynomial Interpolation"];
%             end
%             if ~isempty(app.GaussInterX)
%                 label=[label "Gaussian Interpolation"];
%                 
%             end
%             if ~isempty(app.PolyApproxX)
%                 label=[label "Polynomial Approximation"];
%             end
%             if ~isempty(app.FourierApproxX)
%                 label=[label "Fourier Approximation"];
%             end
%             legend(app.UIAxes,label);
%             hold(app.UIAxes,'off');

        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value=="Interpolation"
                app.AlgorithmDropDown.Items= ["Polynomial","Gaussian"];
                app.PolynomialTermsEditField.Visible=false;
                app.PolynomialTermsLabel.Visible=false;
            else
                app.AlgorithmDropDown.Items= ["Polynomial","Fourier"];
                app.PolynomialTermsEditField.Visible=true;
                app.PolynomialTermsLabel.Visible=true;
            end
            app.PlotAll();
        end

        % Value changed function: AddPointsButton
        function AddPointsButtonValueChanged(app, event)
            if app.InputDropDown.Value~="Manual"
                app.AddPointsButton.Value=false;
            end
            app.DeletePointsButton.Value=false;
            app.EditPointsButton.Value=false;
            app.SelectIndex=nan;
        end

        % Value changed function: EditPointsButton
        function EditPointsButtonValueChanged(app, event)
            if app.InputDropDown.Value~="Manual"
                app.EditPointsButton.Value=false;
            end
            app.DeletePointsButton.Value=false;
            app.AddPointsButton.Value=false;
            app.SelectIndex=nan;
        end

        % Value changed function: DeletePointsButton
        function DeletePointsButtonValueChanged(app, event)
            if app.InputDropDown.Value~="Manual"
                app.DeletePointsButton.Value=false;
            end
            app.EditPointsButton.Value=false;
            app.AddPointsButton.Value=false; 
            app.SelectIndex=nan;
        end

        % Callback function
        function ChafFittingUIFigureButtonDown(app, event)
            
        end

        % Window button up function: ChafFittingUIFigure
        function ChafFittingUIFigureWindowButtonUp(app, event)
            app.ChafFittingUIFigure.SelectionType="open";
        end

        % Callback function
        function AlgorithmDropDownValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: PolynomialInterpolationCheckBox
        function PolynomialInterpolationCheckBoxValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: GaussianInterpolationCheckBox
        function GaussianInterpolationCheckBoxValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: PolynomialApproximationCheckBox
        function PolynomialApproximationCheckBoxValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: FourierApproximationCheckBox
        function FourierApproximationCheckBoxValueChanged(app, event)
            app.PlotAll();
        end

        % Window key press function: ChafFittingUIFigure
        function ChafFittingUIFigureWindowKeyPress(app, event)
            if event.Key=="delete"&&app.AxisActiveButton.Value...
                    &&~isnan(app.SelectIndex)
                app.PointsX=[app.PointsX(1:app.SelectIndex-1),app.PointsX(app.SelectIndex+1:length(app.PointsX))];
                app.PointsY=[app.PointsY(1:app.SelectIndex-1),app.PointsY(app.SelectIndex+1:length(app.PointsY))];
                app.SelectIndex=nan;
                app.PointsNumEditField.Value=length(app.PointsX);
                app.PlotAll();
            end
        end

        % Callback function
        function PolynomialTermsEditFieldValueChanged(app, event)
            app.PlotAll();
        end

        % Callback function
        function FourierTermsEditFieldValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: PolynomialTermsSpinner
        function PolynomialTermsSpinnerValueChanged(app, event)
            app.PlotAll();
        end

        % Value changing function: PolynomialTermsSpinner
        function PolynomialTermsSpinnerValueChanging(app, event)
            app.PlotAll();
        end

        % Value changed function: FourierTermsSpinner
        function FourierTermsSpinnerValueChanged(app, event)
            app.PlotAll();
        end

        % Value changing function: FourierTermsSpinner
        function FourierTermsSpinnerValueChanging(app, event)
            app.PlotAll();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ChafFittingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {424, 424};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {200, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChafFittingUIFigure = uifigure('Visible', 'off');
            app.ChafFittingUIFigure.Name = 'Chaf Fitting';
            app.ChafFittingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ChafFittingUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @ChafFittingUIFigureWindowButtonDown, true);
            app.ChafFittingUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @ChafFittingUIFigureWindowButtonUp, true);
            app.ChafFittingUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.ChafFittingUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ChafFittingUIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.ChafFittingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputDropDownLabel = uilabel(app.LeftPanel);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.LeftPanel);
            app.InputDropDown.Items = {'Auto', 'Manual'};
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownValueChanged, true);
            app.InputDropDown.Value = 'Auto';
            app.ChafFittingLabel = uilabel(app.LeftPanel);
            app.ChafFittingLabel.Text = 'Chaf Fitting';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'face.png';
            app.PointsNumEditFieldLabel = uilabel(app.LeftPanel);
            app.PointsNumEditFieldLabel.Text = 'Points Num';
            app.PointsNumEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointsNumEditField.ValueChangedFcn = createCallbackFcn(app, @PointsNumEditFieldValueChanged, true);
            app.PolynomialInterpolationCheckBox = uicheckbox(app.LeftPanel);
            app.PolynomialInterpolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolynomialInterpolationCheckBoxValueChanged, true);
            app.PolynomialInterpolationCheckBox.Text = 'Polynomial Interpolation';
            app.GaussianInterpolationCheckBox = uicheckbox(app.LeftPanel);
            app.GaussianInterpolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianInterpolationCheckBoxValueChanged, true);
            app.GaussianInterpolationCheckBox.Text = 'Gaussian Interpolation';
            app.PolynomialApproximationCheckBox = uicheckbox(app.LeftPanel);
            app.PolynomialApproximationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolynomialApproximationCheckBoxValueChanged, true);
            app.PolynomialApproximationCheckBox.Text = 'Polynomial Approximation';
            app.FourierApproximationCheckBox = uicheckbox(app.LeftPanel);
            app.FourierApproximationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FourierApproximationCheckBoxValueChanged, true);
            app.FourierApproximationCheckBox.Text = 'Fourier Approximation';
            app.AxisActiveButton = uibutton(app.LeftPanel, 'state');
            app.AxisActiveButton.Visible = 'off';
            app.AxisActiveButton.Text = 'Axis Active';
            app.PolynomialTermsSpinnerLabel = uilabel(app.LeftPanel);
            app.PolynomialTermsSpinnerLabel.Text = 'Polynomial Terms';
            app.PolynomialTermsSpinner = uispinner(app.LeftPanel);
            app.PolynomialTermsSpinner.ValueChangingFcn = createCallbackFcn(app, @PolynomialTermsSpinnerValueChanging, true);
            app.PolynomialTermsSpinner.ValueChangedFcn = createCallbackFcn(app, @PolynomialTermsSpinnerValueChanged, true);
            app.PolynomialTermsSpinner.Value = 3;
            app.FourierTermsSpinnerLabel = uilabel(app.LeftPanel);
            app.FourierTermsSpinnerLabel.Text = 'Fourier Terms';
            app.FourierTermsSpinner = uispinner(app.LeftPanel);
            app.FourierTermsSpinner.ValueChangingFcn = createCallbackFcn(app, @FourierTermsSpinnerValueChanging, true);
            app.FourierTermsSpinner.ValueChangedFcn = createCallbackFcn(app, @FourierTermsSpinnerValueChanged, true);
            app.FourierTermsSpinner.Value = 3;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Fitting')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.AddPointsButton = uibutton(app.RightPanel, 'state');
            app.AddPointsButton.ValueChangedFcn = createCallbackFcn(app, @AddPointsButtonValueChanged, true);
            app.AddPointsButton.Visible = 'off';
            app.AddPointsButton.Text = 'Add Points';
            app.EditPointsButton = uibutton(app.RightPanel, 'state');
            app.EditPointsButton.ValueChangedFcn = createCallbackFcn(app, @EditPointsButtonValueChanged, true);
            app.EditPointsButton.Visible = 'off';
            app.EditPointsButton.Text = 'Edit Points';
            app.DeletePointsButton = uibutton(app.RightPanel, 'state');
            app.DeletePointsButton.ValueChangedFcn = createCallbackFcn(app, @DeletePointsButtonValueChanged, true);
            app.DeletePointsButton.Visible = 'off';
            app.DeletePointsButton.Text = 'Delete Points';
            app.errorLabel = uilabel(app.RightPanel);
            app.errorLabel.Text = 'error';
            app.error1 = uilabel(app.RightPanel);
            app.error1.Text = '0';
            app.error2 = uilabel(app.RightPanel);
            app.error2.Text = '0';
            app.error3 = uilabel(app.RightPanel);
            app.error3.Text = '0';
            app.error4 = uilabel(app.RightPanel);
            app.error4.Text = '0';
            % Show the figure after all components are created
            app.ChafFittingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChafFittingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChafFittingUIFigure)
        end
    end
end