classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        PointsX=[];
        PointsY=[];
        X=0;
        Y=0;
        SelectIndex=nan;
    end
    
    methods (Access = public)
        
        function [] = PlotAll(app)
            cla(app.UIAxes);
            disp(app.PointsX);
            disp(app.PointsY);
            hold(app.UIAxes,"on");
            scatter(app.UIAxes,app.PointsX,app.PointsY,"black","filled");
            if ~isnan(app.SelectIndex)
                scatter(app.UIAxes,app.PointsX(app.SelectIndex),app.PointsY(app.SelectIndex),"red","filled");
                scatter(app.UIAxes,app.PointsX(app.SelectIndex),app.PointsY(app.SelectIndex),10,"red");
            end
            if length(app.PointsX)>1
                plot(app.UIAxes,app.PointsX,app.PointsY,'--g','LineWidth',1);
            end
            [x, y]=Bezier(app.PointsX,app.PointsY);
            plot(app.UIAxes,x,y,"m",'LineWidth',2);
            
            hold(app.UIAxes,"off");
        end
        
        function [] = ClearAll(app)
            app.PointsX=[];
            app.PointsY=[];
            app.X=nan;
            app.Y=nan;
            app.SelectIndex=nan;
            cla(app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            currPt = app.UIAxes.CurrentPoint;
            xp = currPt(1,1);
            yp = currPt(1,2);
            
            if xp < app.UIAxes.XLim(1,1)||...
                    xp > app.UIAxes.XLim(1,2)||...
                    yp < app.UIAxes.YLim(1,1)||...
                    yp > app.UIAxes.YLim(1,2)
               
                app.X = nan;
                app.Y = nan;
               
            else
                app.X = xp;
                app.Y = yp;
            end
            if app.UIFigure.SelectionType=="normal"&&~isnan(app.SelectIndex)
                app.PointsX(app.SelectIndex)=app.X;
                app.PointsY(app.SelectIndex)=app.Y;
                app.PlotAll();
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.UIFigure.SelectionType=="normal"
                dist=((app.PointsX-app.X)/(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))).^2+...
                ((app.PointsY-app.Y)/(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))).^2;
                [minValue, index]=min(dist);
                if minValue<0.001
                    app.SelectIndex=index;
                else
                    if ~isnan(app.X)&&~isnan(app.Y)
                        app.PointsX=[app.PointsX app.X];
                        app.PointsY=[app.PointsY app.Y];
                        app.SelectIndex=length(app.PointsX);
                    end
                end
                app.PlotAll();
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.UIFigure.SelectionType="open";
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if event.Key=="delete"&&~isnan(app.SelectIndex)
                app.PointsX=[app.PointsX(1:app.SelectIndex-1),app.PointsX(app.SelectIndex+1:length(app.PointsX))];
                app.PointsY=[app.PointsY(1:app.SelectIndex-1),app.PointsY(app.SelectIndex+1:length(app.PointsY))];
                app.SelectIndex=nan;
                app.PlotAll();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bezier Curve')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end