classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        CurvatureCircleLabel     matlab.ui.control.Label
        majoraxisEditFieldLabel  matlab.ui.control.Label
        majoraxisEditField       matlab.ui.control.NumericEditField
        minoraxisEditFieldLabel  matlab.ui.control.Label
        minoraxisEditField       matlab.ui.control.NumericEditField
        ClearButton              matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        X=0;
        Y=0;
        EllipseX=[];
        EllipseY=[];
        SelectIndex=[];
    end
    
    methods (Access = public)
        
        function [] = PlotAll(app)
            cla(app.UIAxes);
            hold(app.UIAxes,"on");
            a=app.majoraxisEditField.Value;
            b=app.minoraxisEditField.Value;
            [app.EllipseX,app.EllipseY]=Ellipse(a,b);
            plot(app.UIAxes,app.EllipseX,app.EllipseY,'b','LineWidth',1);
            [x,y]=Evolute(a,b);
            plot(app.UIAxes,x,y,'g','LineWidth',1);
            for index=app.SelectIndex
                scatter(app.UIAxes,app.EllipseX(index),app.EllipseY(index),"red","filled");
                [x,y]=Curvature_Circle(a,b,app.EllipseX(index),app.EllipseY(index));
                plot(app.UIAxes,x,y,'m');
            end
            app.UIAxes.XLim=[-a*2,a*2];
            app.UIAxes.YLim=[-b*2,b*2];
            hold(app.UIAxes,"off");
        end
        
        
        function [] = ClearAll(app)
            app.X=[];
            app.Y=[];
            app.SelectIndex=[];
            app.PlotAll();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotAll();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {381, 381};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {142, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: majoraxisEditField
        function majoraxisEditFieldValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: minoraxisEditField
        function minoraxisEditFieldValueChanged(app, event)
            app.PlotAll();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            dist=((app.EllipseX-app.X)/(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))).^2+...
                    ((app.EllipseY-app.Y)/(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))).^2;
            [minValue, index]=min(dist);
            if minValue<0.001
                app.SelectIndex=[app.SelectIndex index];
            end
            app.PlotAll();
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            currPt = app.UIAxes.CurrentPoint;
                xp = currPt(1,1);
                yp = currPt(1,2);
                
                if xp < app.UIAxes.XLim(1,1)||...
                        xp > app.UIAxes.XLim(1,2)||...
                        yp < app.UIAxes.YLim(1,1)||...
                        yp > app.UIAxes.YLim(1,2)
                   
                    app.X = 0;
                    app.Y = 0;
                   
                else
                    app.X = xp;
                    app.Y = yp;
                end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ClearAll();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurvatureCircleLabel = uilabel(app.LeftPanel);
            app.CurvatureCircleLabel.Text = 'Curvature Circle';
            app.majoraxisEditFieldLabel = uilabel(app.LeftPanel);
            app.majoraxisEditFieldLabel.Text = 'major axis';
            app.majoraxisEditField = uieditfield(app.LeftPanel, 'numeric');
            app.majoraxisEditField.ValueChangedFcn = createCallbackFcn(app, @majoraxisEditFieldValueChanged, true);
            app.majoraxisEditField.Value = 6;
            app.minoraxisEditFieldLabel = uilabel(app.LeftPanel);
            app.minoraxisEditFieldLabel.Text = 'minor axis';
            app.minoraxisEditField = uieditfield(app.LeftPanel, 'numeric');
            app.minoraxisEditField.ValueChangedFcn = createCallbackFcn(app, @minoraxisEditFieldValueChanged, true);
            app.minoraxisEditField.Value = 5;
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end