classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BezierSplineCurveUIFigure      matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        BezierSplineCurveLabel         matlab.ui.control.Label
        EndConditionDropDownLabel      matlab.ui.control.Label
        EndConditionDropDown           matlab.ui.control.DropDown
        ParameterizationDropDownLabel  matlab.ui.control.Label
        ParameterizationDropDown       matlab.ui.control.DropDown
        DisplayControlPointsLinesCheckBox  matlab.ui.control.CheckBox
        DisplayGeneratePointsLinesCheckBox  matlab.ui.control.CheckBox
        ClearButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        PointsX=[];
        PointsY=[];
        delta=[];
        X=0;
        Y=0;
        SelectIndex=nan;
        close=false;
        AMatrix=[];
    end
    
    methods (Access = public)
        
        function [] = ClearAll(app)
            app.PointsX=[];
            app.PointsY=[];
            app.X=nan;
            app.Y=nan;
            app.SelectIndex=nan;
            app.close=false;
            app.AMatrix=[];
            cla(app.UIAxes);
        end
        
        function [] = PlotAll(app)
            cla(app.UIAxes);
            hold(app.UIAxes,"on");

            if length(app.PointsX)>1
%                 A=BezierSplineMatrix(app.PointsX,app.PointsY,app.delta,app.EndConditionDropDown.Value);
                bx=zeros(3*length(app.PointsX)-2,1);
                by=zeros(3*length(app.PointsX)-2,1);
                for i=0:length(app.PointsX)-1
                    bx(i+1)=app.PointsX(i+1);
                    by(i+1)=app.PointsY(i+1);
                end
                
                if app.EndConditionDropDown.Value=="Bessel"
                    c11=-(2*app.delta(1)+app.delta(2))/(3*(app.delta(1)+app.delta(2)));
                    c12=(app.delta(1)+app.delta(2))/(3*app.delta(2));
                    c13=-app.delta(1)^2/(3*app.delta(2)*(app.delta(1)+app.delta(2)));
                    bx(3*length(app.PointsX)-3)=c11*app.PointsX(1)+c12*app.PointsX(2)+c13*app.PointsX(3);
                    by(3*length(app.PointsY)-3)=c11*app.PointsY(1)+c12*app.PointsY(2)+c13*app.PointsY(3);
                    
                    n=length(app.PointsX);
                    c21=app.delta(n-1)^2/(3*app.delta(n-2)*(app.delta(n-1)+app.delta(n-2)));
                    c22=-(app.delta(n-1)+app.delta(n-2))/(3*app.delta(n-2));
                    c23=(app.delta(n-2)+2*app.delta(n-1))/(3*(app.delta(n-2)+app.delta(n-1)));
                    bx(3*length(app.PointsX)-2)=c21*app.PointsX(n-2)+c22*app.PointsX(n-1)+c23*app.PointsX(n);
                    by(3*length(app.PointsY)-2)=c21*app.PointsY(n-2)+c22*app.PointsY(n-1)+c23*app.PointsY(n);
                end
                
                newX=app.AMatrix*bx;
                newY=app.AMatrix*by;
                
                [x,y]=BezierSpline(newX',newY');
                if app.DisplayGeneratePointsLinesCheckBox.Value
                    plot(app.UIAxes,newX,newY,"--b",'LineWidth',2);
                    scatter(app.UIAxes,newX,newY,"blue","filled");
                end         
                if app.DisplayControlPointsLinesCheckBox.Value
                plot(app.UIAxes,app.PointsX,app.PointsY,'--g','LineWidth',1);
                end
                plot(app.UIAxes,x,y,"m",'LineWidth',2);    
            end
            
            scatter(app.UIAxes,app.PointsX,app.PointsY,"yellow","filled");
            if ~isnan(app.SelectIndex)
                scatter(app.UIAxes,app.PointsX(app.SelectIndex),app.PointsY(app.SelectIndex),"red","filled");
            end
            hold(app.UIAxes,"off");
        end
        
        function [] = Update(app)
            value = app.ParameterizationDropDown.Value;
            if value=="Equidistant"
                app.delta=Parameter_Uniform(app.PointsX);
            elseif value=="Centripetal "
                app.delta=Parameter_Centripetal(app.PointsX,app.PointsY);
            elseif value=="Chordal "
                app.delta=Parameter_Chordal(app.PointsX,app.PointsY);
            end
            A=BezierSplineMatrix(length(app.PointsY),app.delta,app.EndConditionDropDown.Value);
            app.AMatrix=A^(-1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button motion function: BezierSplineCurveUIFigure
        function BezierSplineCurveUIFigureWindowButtonMotion(app, event)
            currPt = app.UIAxes.CurrentPoint;
            xp = currPt(1,1);
            yp = currPt(1,2);
            
            if xp < app.UIAxes.XLim(1,1)||...
                    xp > app.UIAxes.XLim(1,2)||...
                    yp < app.UIAxes.YLim(1,1)||...
                    yp > app.UIAxes.YLim(1,2)
               
                app.X = nan;
                app.Y = nan;
               
            else
                app.X = xp;
                app.Y = yp;
            end
            if app.BezierSplineCurveUIFigure.SelectionType=="normal"&&~isnan(app.SelectIndex)
                                
                app.PointsX(app.SelectIndex)=app.X;
                app.PointsY(app.SelectIndex)=app.Y;
                
                if app.SelectIndex==length(app.PointsX)||app.SelectIndex==1
                    if app.close
                        app.PointsX(1)=app.X;
                        app.PointsX(length(app.PointsX))=app.X;
                        app.PointsY(1)=app.Y;
                        app.PointsY(length(app.PointsY))=app.Y;
                    else
                        dist=((app.PointsX(1)-app.PointsX(length(app.PointsX)))/(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))).^2+...
                    ((app.PointsY(1)-app.PointsY(length(app.PointsY)))/(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))).^2;
                        if dist<0.001
                            app.PointsX(1)=app.X;
                            app.PointsX(length(app.PointsX))=app.X;
                            app.PointsY(1)=app.Y;
                            app.PointsY(length(app.PointsY))=app.Y;
                            app.close=true;
                            app.EndConditionDropDown.Value="Close";
                            app.Update();
                        end
                    end
                end
                
                app.PlotAll();
            end
            
        end

        % Window button down function: BezierSplineCurveUIFigure
        function BezierSplineCurveUIFigureWindowButtonDown(app, event)
            if app.BezierSplineCurveUIFigure.SelectionType=="normal"
                if ~isempty(app.PointsX)
                    dist=((app.PointsX-app.X)/(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))).^2+...
                    ((app.PointsY-app.Y)/(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))).^2;
                    [minValue, index]=min(dist);
                    if minValue<0.001
                        app.SelectIndex=index;
                    else
                        if ~isnan(app.X)&&~isnan(app.Y)
                            app.PointsX=[app.PointsX app.X];
                            app.PointsY=[app.PointsY app.Y];
                            app.SelectIndex=length(app.PointsX);
                            if app.EndConditionDropDown.Value=="Close"
                                app.close=false;
                                app.EndConditionDropDown.Value="Natural";
                            end
                            app.Update();
                        end
                    end
                else
                    app.PointsX=[app.PointsX app.X];
                    app.PointsY=[app.PointsY app.Y];
                    app.SelectIndex=length(app.PointsX);
                end
                app.PlotAll();
            end
        end

        % Window button up function: BezierSplineCurveUIFigure
        function BezierSplineCurveUIFigureWindowButtonUp(app, event)
            app.BezierSplineCurveUIFigure.SelectionType="open";
        end

        % Window key press function: BezierSplineCurveUIFigure
        function BezierSplineCurveUIFigureWindowKeyPress(app, event)
            if event.Key=="delete"&&~isnan(app.SelectIndex)
                app.PointsX=[app.PointsX(1:app.SelectIndex-1),app.PointsX(app.SelectIndex+1:length(app.PointsX))];
                app.PointsY=[app.PointsY(1:app.SelectIndex-1),app.PointsY(app.SelectIndex+1:length(app.PointsY))];
                app.SelectIndex=nan;
                app.Update();
                app.PlotAll();
            end
            
        end

        % Value changed function: EndConditionDropDown
        function EndConditionDropDownValueChanged(app, event)
            app.Update();
            if app.EndConditionDropDown.Value~="Close"&&app.close
                app.close=false;
                app.PointsX(1)=app.PointsX(length(app.PointsX))+0.03/(app.UIAxes.XLim(2)-app.UIAxes.XLim(1));
                app.PointsY(1)=app.PointsY(length(app.PointsY))+0.03/(app.UIAxes.YLim(2)-app.UIAxes.YLim(1));
            elseif app.EndConditionDropDown.Value=="Close"
                app.PointsX(1)=app.PointsX(length(app.PointsX));
                app.PointsY(1)=app.PointsY(length(app.PointsY));
            end
            app.PlotAll();
        end

        % Value changed function: ParameterizationDropDown
        function ParameterizationDropDownValueChanged(app, event)
            app.Update();
            app.PlotAll();
        end

        % Value changed function: DisplayControlPointsLinesCheckBox
        function DisplayControlPointsLinesCheckBoxValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: DisplayGeneratePointsLinesCheckBox
        function DisplayGeneratePointsLinesCheckBoxValueChanged(app, event)
            app.PlotAll();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ClearAll();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BezierSplineCurveUIFigure = uifigure('Visible', 'off');
            app.BezierSplineCurveUIFigure.Name = 'Bezier Spline Curve';
            app.BezierSplineCurveUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @BezierSplineCurveUIFigureWindowButtonDown, true);
            app.BezierSplineCurveUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @BezierSplineCurveUIFigureWindowButtonUp, true);
            app.BezierSplineCurveUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @BezierSplineCurveUIFigureWindowButtonMotion, true);
            app.BezierSplineCurveUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @BezierSplineCurveUIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.BezierSplineCurveUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.BezierSplineCurveLabel = uilabel(app.BezierSplineCurveUIFigure);
            app.BezierSplineCurveLabel.Text = 'Bezier Spline Curve';
            app.EndConditionDropDownLabel = uilabel(app.BezierSplineCurveUIFigure);
            app.EndConditionDropDownLabel.Text = 'End Condition';
            app.EndConditionDropDown = uidropdown(app.BezierSplineCurveUIFigure);
            app.EndConditionDropDown.Items = {'Natural', 'Bessel', 'Close'};
            app.EndConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @EndConditionDropDownValueChanged, true);
            app.EndConditionDropDown.Value = 'Natural';
            app.ParameterizationDropDownLabel = uilabel(app.BezierSplineCurveUIFigure);
            app.ParameterizationDropDownLabel.Text = 'Parameterization';
            app.ParameterizationDropDown = uidropdown(app.BezierSplineCurveUIFigure);
            app.ParameterizationDropDown.Items = {'Equidistant', 'Centripetal ', 'Chordal '};
            app.ParameterizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterizationDropDownValueChanged, true);
            app.ParameterizationDropDown.Value = 'Equidistant';
            app.DisplayControlPointsLinesCheckBox = uicheckbox(app.BezierSplineCurveUIFigure);
            app.DisplayControlPointsLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayControlPointsLinesCheckBoxValueChanged, true);
            app.DisplayControlPointsLinesCheckBox.Text = 'Display Control Points Lines';
            app.DisplayControlPointsLinesCheckBox.Value = true;
            app.DisplayGeneratePointsLinesCheckBox = uicheckbox(app.BezierSplineCurveUIFigure);
            app.DisplayGeneratePointsLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayGeneratePointsLinesCheckBoxValueChanged, true);
            app.DisplayGeneratePointsLinesCheckBox.Text = 'Display Generate Points Lines';
            app.DisplayGeneratePointsLinesCheckBox.Value = true;
            app.ClearButton = uibutton(app.BezierSplineCurveUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Image = uiimage(app.BezierSplineCurveUIFigure);
            app.Image.ImageSource = 'a.png';
            % Show the figure after all components are created
            app.BezierSplineCurveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BezierSplineCurveUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BezierSplineCurveUIFigure)
        end
    end
end