classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BezierSplineCurveUIFigure  matlab.ui.Figure
        BSplineCurveLabel          matlab.ui.control.Label
        TypeDropDownLabel          matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        DisplayControlPointsLinesCheckBox  matlab.ui.control.CheckBox
        DisplayGeneratePointsLinesCheckBox  matlab.ui.control.CheckBox
        ClearButton                matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        PointsX=[];
        PointsY=[];
        delta=[];
        feature=[];
        A=[];
        status=1;
        X=0;
        Y=0;
        SelectIndex=nan;
        AMatrix=[];
    end
    
    methods (Access = public)
        
        function [] = ClearAll(app)
            app.PointsX=[];
            app.PointsY=[];
            app.feature=[];
            app.A=[];
            app.X=nan;
            app.Y=nan;
            app.SelectIndex=nan;
            app.AMatrix=[];
            cla(app.UIAxes);
        end
        
        function [] = PlotAll(app)
            cla(app.UIAxes);
            hold(app.UIAxes,"on");

            if length(app.PointsX)>1

                if app.DisplayControlPointsLinesCheckBox.Value
                    plot(app.UIAxes,app.PointsX,app.PointsY,'--g','LineWidth',1);
                end
                
                [dx,dy] = DeBoorPoints(app.feature,app.PointsX,app.PointsY);
                
                if app.DisplayGeneratePointsLinesCheckBox.Value
                    scatter(app.UIAxes,dx,dy,"blue","s","filled");
                    plot(app.UIAxes,dx,dy,"--blue");
                end
                [x,y]=BSpline(dx,dy,4,app.feature);
                plot(app.UIAxes,x,y,'m','LineWidth',3);
                
            end
            
            
            for i=1:length(app.PointsX)
                color=[1 0 0];
                if app.feature(i)==1
                    color=color*1;
                elseif app.feature(i)==2
                    color=color*0.6;
                elseif app.feature(i)==3
                    color=color*0.3;
                end
                scatter(app.UIAxes,app.PointsX(i),app.PointsY(i),60,color,"filled");
                
            end


            if ~isnan(app.SelectIndex)
                scatter(app.UIAxes,app.PointsX(app.SelectIndex),app.PointsY(app.SelectIndex),60,"white","filled");
            end
            hold(app.UIAxes,"off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button motion function: BezierSplineCurveUIFigure
        function BezierSplineCurveUIFigureWindowButtonMotion(app, event)
            currPt = app.UIAxes.CurrentPoint;
            xp = currPt(1,1);
            yp = currPt(1,2);
            
            if xp < app.UIAxes.XLim(1,1)||...
                    xp > app.UIAxes.XLim(1,2)||...
                    yp < app.UIAxes.YLim(1,1)||...
                    yp > app.UIAxes.YLim(1,2)
               
                app.X = nan;
                app.Y = nan;
               
            else
                app.X = xp;
                app.Y = yp;
            end
            if app.BezierSplineCurveUIFigure.SelectionType=="normal"&&~isnan(app.SelectIndex)
                                
                app.PointsX(app.SelectIndex)=app.X;
                app.PointsY(app.SelectIndex)=app.Y;
                app.PlotAll();
            end
            
        end

        % Window button down function: BezierSplineCurveUIFigure
        function BezierSplineCurveUIFigureWindowButtonDown(app, event)
            if app.BezierSplineCurveUIFigure.SelectionType=="normal"
                
                if ~isempty(app.PointsX)
                    dist=((app.PointsX-app.X)/(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))).^2+...
                    ((app.PointsY-app.Y)/(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))).^2;
                    [minValue, index]=min(dist);
                    if minValue<0.001
                        app.SelectIndex=index;
                    else
                        if ~isnan(app.X)&&~isnan(app.Y)
                            app.PointsX=[app.PointsX app.X];
                            app.PointsY=[app.PointsY app.Y];
                            app.SelectIndex=length(app.PointsX);
                            app.feature=[app.feature app.status];
                        end
                    end
                else
                    app.feature=[app.feature app.status];
                    app.PointsX=[app.PointsX app.X];
                    app.PointsY=[app.PointsY app.Y];
                    app.SelectIndex=length(app.PointsX);
                end
                app.PlotAll();
            end
        end

        % Window button up function: BezierSplineCurveUIFigure
        function BezierSplineCurveUIFigureWindowButtonUp(app, event)
            app.BezierSplineCurveUIFigure.SelectionType="open";
        end

        % Window key press function: BezierSplineCurveUIFigure
        function BezierSplineCurveUIFigureWindowKeyPress(app, event)
            if event.Key=="delete"&&~isnan(app.SelectIndex)
                app.PointsX=[app.PointsX(1:app.SelectIndex-1),app.PointsX(app.SelectIndex+1:length(app.PointsX))];
                app.PointsY=[app.PointsY(1:app.SelectIndex-1),app.PointsY(app.SelectIndex+1:length(app.PointsY))];
                app.feature=[app.feature(1:app.SelectIndex-1),app.feature(app.SelectIndex+1:length(app.feature))];
                app.SelectIndex=nan;
                app.PlotAll();
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if app.TypeDropDown.Value=="C2"
                app.status=1;
            elseif app.TypeDropDown.Value=="Line"
                app.status=2;
            elseif app.TypeDropDown.Value=="Shape Point"
                app.status=3;
            end
            app.PlotAll();
        end

        % Callback function
        function ParameterizationDropDownValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: DisplayControlPointsLinesCheckBox
        function DisplayControlPointsLinesCheckBoxValueChanged(app, event)
            app.PlotAll();
        end

        % Value changed function: DisplayGeneratePointsLinesCheckBox
        function DisplayGeneratePointsLinesCheckBoxValueChanged(app, event)
            app.PlotAll();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ClearAll();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BezierSplineCurveUIFigure = uifigure('Visible', 'off');
            app.BezierSplineCurveUIFigure.Name = 'Bezier Spline Curve';
            app.BezierSplineCurveUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @BezierSplineCurveUIFigureWindowButtonDown, true);
            app.BezierSplineCurveUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @BezierSplineCurveUIFigureWindowButtonUp, true);
            app.BezierSplineCurveUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @BezierSplineCurveUIFigureWindowButtonMotion, true);
            app.BezierSplineCurveUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @BezierSplineCurveUIFigureWindowKeyPress, true);
            app.BSplineCurveLabel = uilabel(app.BezierSplineCurveUIFigure);
            app.BSplineCurveLabel.Text = 'B Spline Curve';
            app.TypeDropDownLabel = uilabel(app.BezierSplineCurveUIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.BezierSplineCurveUIFigure);
            app.TypeDropDown.Items = {'C2', 'Line', 'Shape Point'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'C2';
            app.DisplayControlPointsLinesCheckBox = uicheckbox(app.BezierSplineCurveUIFigure);
            app.DisplayControlPointsLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayControlPointsLinesCheckBoxValueChanged, true);
            app.DisplayControlPointsLinesCheckBox.Text = 'Display Control Points Lines';
            app.DisplayControlPointsLinesCheckBox.Value = true;
            app.DisplayGeneratePointsLinesCheckBox = uicheckbox(app.BezierSplineCurveUIFigure);
            app.DisplayGeneratePointsLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayGeneratePointsLinesCheckBoxValueChanged, true);
            app.DisplayGeneratePointsLinesCheckBox.Text = 'Display Generate Points Lines';
            app.DisplayGeneratePointsLinesCheckBox.Value = true;
            app.ClearButton = uibutton(app.BezierSplineCurveUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Image = uiimage(app.BezierSplineCurveUIFigure);
            app.Image.ImageSource = 'a.png';
            app.UIAxes = uiaxes(app.BezierSplineCurveUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            % Show the figure after all components are created
            app.BezierSplineCurveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BezierSplineCurveUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BezierSplineCurveUIFigure)
        end
    end
end